classdef APP_CCDCalib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadcamerafileButton         matlab.ui.control.Button
        LoadFOVreferenceButton       matlab.ui.control.Button
        SendtoPreProcButton          matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        LoadROIPositionButton        matlab.ui.control.Button
        EditField_3                  matlab.ui.control.EditField
        LogScaleCheckBox             matlab.ui.control.CheckBox
        BackgroundSubtractionButton  matlab.ui.control.Button
        BackgroundROIButton          matlab.ui.control.Button
        ThresholdforMaskSliderLabel  matlab.ui.control.Label
        ThresholdforMaskSlider       matlab.ui.control.Slider
        EditField_4                  matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        camera
        fig
        camera_new
        fig_2
        cbar
        ImageROI
        MaskCCD
        thresh
      % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'CCD file loading and calibration';
        end

        % Button pushed function: LoadcamerafileButton
        function LoadcamerafileButtonPushed(app, event)
            if strcmp(app.EditField.Value,'')==1
            [file, path] = uigetfile('*.mat');
            app.LoadcamerafileButton.Enable = 0;
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField.Value = [path file];
            a = load([path file]);
            else
               a = load(app.EditField.Value); 
            end
            
            if isfield(a,'cmos')
                app.camera = a.cmos;
                app.camera_new = app.camera;
                app.LoadFOVreferenceButton.Enable = 0;
                app.UIAxes2.Visible = 0;
            else
            app.camera = a.camera;
            app.camera = squeeze(app.camera(:,:,1,2)-app.camera(:,:,1,1));
            app.LoadFOVreferenceButton.Enable = 1;
            end
            app.fig = imagesc(app.UIAxes,app.camera); axis(app.UIAxes,'image');
            app.thresh = 0;
        end

        % Button pushed function: SendtoPreProcButton
        function SendtoPreProcButtonPushed(app, event)
            assignin('base', 'camera', app.camera_new);
            assignin('base', 'MaskCCD',app.MaskCCD);
            assignin('base', 'CCDThreshold',app.thresh);
            app.delete;
        end

        % Button pushed function: LoadFOVreferenceButton
        function LoadFOVreferenceButtonPushed(app, event)
            if strcmp(app.EditField_2.Value,'')==1
            [file, path] = uigetfile('*.mat');
            
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField_2.Value = [path file];
            a = load([path file]);
            else
               a = load(app.EditField_2.Value); 
            end
            app.camera_new = fliplr(imwarp(app.camera,a.tform,'OutputView',imref2d(a.pixels)));
            app.fig_2 = imagesc(app.UIAxes2,app.camera_new); axis(app.UIAxes2,'image');
            
            app.LoadROIPositionButton.Enable = 1;
            app.ThresholdforMaskSlider.Limits = app.UIAxes2.CLim;
            %app.cbar = colorbar(app.UIAxes2);
            %app.cbar.Visible = "off";
            app.MaskCCD = true(size(app.camera_new));
        end

        % Button pushed function: LoadROIPositionButton
        function LoadROIPositionButtonPushed(app, event)
            if strcmp(app.EditField_3.Value,'')==1
            [file, path] = uigetfile('*.mat');
            
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField_3.Value = [path file];
            a = load([path file]);
            else
               a = load(app.EditField_3.Value); 
            end
            custom_ROI = round(a.custom_ROI);
            app.camera_new = app.camera_new((custom_ROI(2):(custom_ROI(2)+custom_ROI(4))),(custom_ROI(1):(custom_ROI(1)+custom_ROI(3))));
            app.fig_2.CData = app.camera_new;
            %app.UIAxes2.CLim(1) = min(min(app.camera_new));
            %app.UIAxes2.CLim(2) = max(max(app.camera_new));
            app.ThresholdforMaskSlider.Limits = app.UIAxes2.CLim;
            %app.cbar = colorbar(app.UIAxes2);
            %app.cbar.Visible = "off";
            app.MaskCCD = true(size(app.camera_new));
        end

        % Value changed function: LogScaleCheckBox
        function LogScaleCheckBoxValueChanged(app, event)
            value = app.LogScaleCheckBox.Value;
            if value
                app.fig_2.CData = log(abs(app.fig_2.CData));
            else
                app.fig_2.CData = exp(app.fig_2.CData);
            end
        end

        % Button pushed function: BackgroundROIButton
        function BackgroundROIButtonPushed(app, event)
            if size(app.ImageROI,1) > 0
                delete(app.ImageROI)
            end
            app.ImageROI = drawrectangle(app.UIAxes2);
            app.ImageROI.Position = round(app.ImageROI.Position)+0.5;
            app.BackgroundSubtractionButton.Enable = 1;
        end

        % Button pushed function: BackgroundSubtractionButton
        function BackgroundSubtractionButtonPushed(app, event)
            app.LogScaleCheckBox.Value = 0;
            LogScaleCheckBoxValueChanged(app);
            ROI = app.ImageROI.Position-0.5;
            app.camera_new = app.camera_new - mean(app.camera_new(ROI(2):(ROI(2)+ROI(4)-1),ROI(1):(ROI(1)+ROI(3)-1)),'all');
            app.fig_2.CData = app.camera_new;
            %app.cbar = colorbar(app.UIAxes2);
            %app.cbar.Visible = "off";
            %LogScaleCheckBoxValueChanged(app);
            app.LogScaleCheckBox.Value = 0;
            app.BackgroundROIButton.Enable = 0;
            app.BackgroundSubtractionButton.Enable = 0;
        end

        % Value changing function: ThresholdforMaskSlider
        function ThresholdforMaskSliderValueChanging(app, event)
            app.LogScaleCheckBox.Value = 0;
            LogScaleCheckBoxValueChanged(app);
            changingValue = event.Value;
            app.MaskCCD = app.camera_new>changingValue;
            app.fig_2.CData = app.camera_new.*app.MaskCCD;
            app.EditField_4.Value = changingValue;
        end

        % Value changed function: ThresholdforMaskSlider
        function ThresholdforMaskSliderValueChanged(app, event)
            value = app.ThresholdforMaskSlider.Value;
            app.MaskCCD = app.camera_new>value;
            app.thresh = value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.LogScaleCheckBox.Value = 0;
            LogScaleCheckBoxValueChanged(app);
            value = app.EditField_4.Value;
            app.ThresholdforMaskSlider.Value = value;
            app.MaskCCD = app.camera_new>value;
            app.fig_2.CData = app.camera_new.*app.MaskCCD;
            ThresholdforMaskSliderValueChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadcamerafileButton = uibutton(app.UIFigure, 'push');
            app.LoadcamerafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcamerafileButtonPushed, true);
            app.LoadcamerafileButton.Text = 'Load camera file';
            app.LoadFOVreferenceButton = uibutton(app.UIFigure, 'push');
            app.LoadFOVreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFOVreferenceButtonPushed, true);
            app.LoadFOVreferenceButton.Text = 'Load FOV reference';
            app.SendtoPreProcButton = uibutton(app.UIFigure, 'push');
            app.SendtoPreProcButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoPreProcButtonPushed, true);
            app.SendtoPreProcButton.Text = 'Send to PreProc';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.LoadROIPositionButton = uibutton(app.UIFigure, 'push');
            app.LoadROIPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIPositionButtonPushed, true);
            app.LoadROIPositionButton.Enable = 'off';
            app.LoadROIPositionButton.Text = 'Load ROI Position';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.LogScaleCheckBox = uicheckbox(app.UIFigure);
            app.LogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogScaleCheckBoxValueChanged, true);
            app.LogScaleCheckBox.Text = 'Log Scale';
            app.BackgroundSubtractionButton = uibutton(app.UIFigure, 'push');
            app.BackgroundSubtractionButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundSubtractionButtonPushed, true);
            app.BackgroundSubtractionButton.Enable = 'off';
            app.BackgroundSubtractionButton.Text = 'Background Subtraction';
            app.BackgroundROIButton = uibutton(app.UIFigure, 'push');
            app.BackgroundROIButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundROIButtonPushed, true);
            app.BackgroundROIButton.Text = 'Background ROI';
            app.ThresholdforMaskSliderLabel = uilabel(app.UIFigure);
            app.ThresholdforMaskSliderLabel.Text = 'Threshold for Mask';
            app.ThresholdforMaskSlider = uislider(app.UIFigure);
            app.ThresholdforMaskSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdforMaskSliderValueChanged, true);
            app.ThresholdforMaskSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdforMaskSliderValueChanging, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_CCDCalib

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end