classdef APP_FitArea < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadfromworkspaceButton         matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        DontfitbelowthisthresholdLabel  matlab.ui.control.Label
        DontfitbelowthisthresholdEditField  matlab.ui.control.NumericEditField
        FittheareaButton                matlab.ui.control.Button
        FLIMMapGeneratorLabel           matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        IntegralwithinthespectralROICheckBox  matlab.ui.control.CheckBox
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        chEditFieldLabel                matlab.ui.control.Label
        chEditField                     matlab.ui.control.NumericEditField
        RedcirclepositionLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pixel_histog
        im
        threshold
        sample
        h
        lifetime
        concentration
        residuals
        pixelxplore
        pointer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = evalin('base','im');
            im = evalin('base','data');
            app.im = data;
            app.h = evalin('base','ROI');
            app.sample = imagesc(app.UIAxes,data); axis(app.UIAxes,'image');
            hold(app.UIAxes,'on');
            disableDefaultInteractivity(app.UIAxes)
            drawrectangle(app.UIAxes,'Position',app.h);
            app.pixel_histog = histogram(app.UIAxes2, data,numel(data));
            app.UIAxes2.YLim = [0 1.2*max(app.pixel_histog.Values)];
            app.threshold = line(app.UIAxes2,[app.DontfitbelowthisthresholdEditField.Value app.DontfitbelowthisthresholdEditField.Value],[0 app.UIAxes2.YLim(2)],'color','r');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                im = sum(im,2);
            end
            app.YEditField.Value = app.h(2);
            app.XEditField.Value = app.h(1);
            app.pointer = plot(app.UIAxes,app.h(1),app.h(2),'marker','o','MarkerSize',10,'Color','red');
            app.pixelxplore = plot(app.UIAxes3,evalin('base','t'),squeeze(im(:,app.chEditField.Value,app.YEditField.Value-app.h(2)+1,app.XEditField.Value-app.h(1)+1)));
            grid(app.UIAxes3,'minor')
            box(app.UIAxes3,'on')
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Value changed function: DontfitbelowthisthresholdEditField
        function DontfitbelowthisthresholdEditFieldValueChanged(app, event)
            value = app.DontfitbelowthisthresholdEditField.Value;
            app.threshold.XData = [value value];
            area = app.im;
            area(area<value) = 0;
            app.sample.CData = area;
            colormap(app.UIAxes, [0 0 0; parula(256)] )
            drawnow
        end

        % Button pushed function: FittheareaButton
        function FittheareaButtonPushed(app, event)
            app.FittheareaButton.Enable = 0;
            
            im = evalin('base','data');
            opts = evalin('base','opts');
            t = evalin('base','t');
            ft = evalin('base','func');
            typefun = evalin('base','type_fun');
            N = evalin('base','N');
            start_points = evalin('base','start_points');
            
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                im = sum(im,2);
            end
            
            app.lifetime = zeros(size(im,2),size(app.im,1),size(app.im,2),N);
            app.residuals = zeros(size(im,2),size(app.im,1),size(app.im,2));
            app.concentration = zeros(size(im,2),size(app.im,1),size(app.im,2),N);
            
            res = coeffvalues(start_points);
            opts.StartPoint = res;
            if strcmp(typefun,'Exp. Decay + IRF')
                opts.Upper(1) = 1.05.*res(1);
                opts.Lower(1) = 0.95.*res(1);
                opts.Upper(2) = 1.05.*res(2);
                opts.Lower(2) = 0.95.*res(2);
            end


            for li = 1:size(app.lifetime,1)
                xi = 1; 
                for x = app.h(2):(app.h(2)+app.h(4))
                    yi = 1;
                    for y = app.h(1):(app.h(1)+app.h(3))
                        if app.im(x,y) > app.DontfitbelowthisthresholdEditField.Value
                            
                            if strcmp(typefun,'Exp. Decay + IRF')
                                opts.StartPoint(3:2:(end-1)) = max(im(:,li,xi,yi))/N;
                                opts.Upper(3:2:(end-1)) = 2*max(im(:,li,xi,yi));
                            else
                                opts.StartPoint(1:2:(end-1)) = max(im(:,li,xi,yi))/N;
                                opts.Upper(1:2:(end-1)) = 2*max(im(:,li,xi,yi));
                            end
                            
                            [fitresult] = fit(t, im(:,li,xi,yi), ft, opts);
                            %figure,plot(fitresult,t, im(:,li,xi,yi))
                            res = coeffvalues(fitresult);
                            
                            if strcmp(typefun,'Exp. Decay + IRF')
                                res(1:2) = [];
                            end
                            
                            app.lifetime(li,x,y,:) = 1./res(2:2:end);
                            app.concentration(li,x,y,:) = res(1:2:(end-1))./sum(res(1:2:(end-1)));
                            app.residuals(li,x,y) = abs(sum(fitresult(t)-squeeze(im(:,li,xi,yi))));
                        end
                        yi = yi + 1;
                    end
                    xi = xi + 1;
                end
            end
            
            % Plot the results
            for li = 1:size(app.lifetime,1)
                for n = 1:N
                    figure(li), subplot(1,N+1,n),
                    I(:,:,1) = squeeze(app.lifetime(li,:,:,n))./max(max(squeeze(app.lifetime(li,:,:,n))));
                    % Now I is from 0 to 1. To avoid 1s and 0s to be both
                    % colored in red, I limit the color wheel to a degree
                    % lower than 360° (both 0° and 360° are red). The limit
                    % is 0°,260°
                    I(:,:,1) = I(:,:,1).*(260/360);
                    % Additionally, I want shot times in blue and long in
                    % red:
                    I(:,:,1) =  (260/360).*(squeeze(I(:,:,1))>0) - I(:,:,1);
                    I(:,:,2) = ones([size(I,1) size(I,2)]);
                    I(:,:,3) = squeeze(app.concentration(li,:,:,n));
                    imagesc(hsv2rgb(I)),axis image,
                    hsv = [flipud(linspace(0,260/360,255)); ones(1,255);ones(1,255)]';
                    rgb = hsv2rgb(hsv);
                    colormap(flipud(rgb))
                    cb = colorbar;
                    cb.Ticks = linspace(0,1,8);
                    cb.TickLabels = round(linspace(0,max(max(squeeze(app.lifetime(li,:,:,n)))),length(cb.Ticks)),1);
                    title(['Composite lifetime map for \tau_',num2str(n)])
                end
                resplot = subplot(1,N+1,N+1);
                imagesc(squeeze(app.residuals(li,:,:)));axis image,colormap(resplot,'parula'),colorbar
                title('Residuals')
            end
            app.FittheareaButton.Enable = 1;
            
            for li=1:size(app.lifetime,1)
            figure(size(app.lifetime,1)+li)
            for n = 1:N
                subplot(1,N,n)
                taus = squeeze(app.lifetime(li,:,:,n));
                histogram(taus(taus>0),round(numel(app.lifetime(li,:,:,n))/30))
            end
            end
            
            assignin('base','lifetime',app.lifetime)
            assignin('base','concentration',app.concentration)
        end

        % Button pushed function: LoadfromworkspaceButton
        function LoadfromworkspaceButtonPushed(app, event)
            startupFcn(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            path = evalin('base','path');
            lifetime = app.lifetime;
            residuals = app.residuals;
            concentrations = app.concentration;
            path = path(1:end-4);
            uisave(["lifetime","residuals","concentrations"],[path,'_FLIM.mat'])
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            data = evalin('base','data');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                data = sum(data,2);
            end
                app.XEditField.Value(app.XEditField.Value<app.h(1)) = app.h(1);
                app.XEditField.Value(app.XEditField.Value > app.h(1)+app.h(3)-1) = app.h(1)+app.h(3)-1;
            app.pixelxplore.YData = squeeze(data(:,app.chEditField.Value,app.YEditField.Value-app.h(2)+1,app.XEditField.Value-app.h(1)+1));
            app.pointer.XData = app.XEditField.Value;
            app.pointer.YData = app.YEditField.Value;
            drawnow
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            data = evalin('base','data');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                data = sum(data,2);
            end
            app.YEditField.Value(app.YEditField.Value<app.h(2)) = app.h(2);
            app.YEditField.Value(app.YEditField.Value > app.h(2)+app.h(4)-1) = app.h(2)+app.h(4)-1;
            app.pixelxplore.YData = squeeze(data(:,app.chEditField.Value,app.YEditField.Value-app.h(2)+1,app.XEditField.Value-app.h(1)+1));
            app.pointer.XData = app.XEditField.Value;
            app.pointer.YData = app.YEditField.Value;
            drawnow
        end

        % Value changed function: chEditField
        function chEditFieldValueChanged(app, event)
            data = evalin('base','data');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                data = sum(data,2);
            end
            app.chEditField.Value(app.chEditField.Value<1) = 1;
            app.chEditField.Value(app.chEditField.Value > size(data,2)) = size(data,2);
            app.pixelxplore.YData = squeeze(data(:,app.chEditField.Value,app.YEditField.Value-app.h(2)+1,app.XEditField.Value-app.h(1)+1));
            drawnow
        end

        % Value changed function: 
        % IntegralwithinthespectralROICheckBox
        function IntegralwithinthespectralROICheckBoxValueChanged(app, event)
            data = evalin('base','data');
            if app.IntegralwithinthespectralROICheckBox.Value == 1
                data = sum(data,2);
                app.chEditField.Value = 1;
            end
            app.pixelxplore.YData = squeeze(data(:,app.chEditField.Value,app.YEditField.Value-app.h(2)+1,app.XEditField.Value-app.h(1)+1));
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadfromworkspaceButton = uibutton(app.UIFigure, 'push');
            app.LoadfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromworkspaceButtonPushed, true);
            app.LoadfromworkspaceButton.Text = 'Load from workspace';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.DontfitbelowthisthresholdLabel = uilabel(app.UIFigure);
            app.DontfitbelowthisthresholdLabel.Text = 'Don''t''fit below this threshold:';
            app.DontfitbelowthisthresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DontfitbelowthisthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @DontfitbelowthisthresholdEditFieldValueChanged, true);
            app.FittheareaButton = uibutton(app.UIFigure, 'push');
            app.FittheareaButton.ButtonPushedFcn = createCallbackFcn(app, @FittheareaButtonPushed, true);
            app.FittheareaButton.Text = 'Fit the area';
            app.FLIMMapGeneratorLabel = uilabel(app.UIFigure);
            app.FLIMMapGeneratorLabel.Text = 'FLIM Map Generator';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.IntegralwithinthespectralROICheckBox = uicheckbox(app.UIFigure);
            app.IntegralwithinthespectralROICheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegralwithinthespectralROICheckBoxValueChanged, true);
            app.IntegralwithinthespectralROICheckBox.Text = 'Integral within the spectral ROI';
            app.IntegralwithinthespectralROICheckBox.Value = true;
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Value = 1;
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Value = 1;
            app.chEditFieldLabel = uilabel(app.UIFigure);
            app.chEditFieldLabel.Text = 'ch';
            app.chEditField = uieditfield(app.UIFigure, 'numeric');
            app.chEditField.ValueChangedFcn = createCallbackFcn(app, @chEditFieldValueChanged, true);
            app.chEditField.Value = 1;
            app.RedcirclepositionLabel = uilabel(app.UIFigure);
            app.RedcirclepositionLabel.Text = 'Red circle position:';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity of each pixel within the image')
            xlabel(app.UIAxes2, 'Intensity')
            ylabel(app.UIAxes2, 'Number of pixel of a given intensity')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pixel explorer')
            xlabel(app.UIAxes3, 'Time [ns]')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_FitArea

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end